classdef mImageColorAnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageColorAnalysisToolUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ClusterImageMenu                matlab.ui.container.Menu
        ColorHistImageMenu              matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        SetClusterNumberMenu            matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        OriginTab                       matlab.ui.container.Tab
        UIAxesOirigin                   matlab.ui.control.UIAxes
        ClusterTab                      matlab.ui.container.Tab
        UIAxesCluster                   matlab.ui.control.UIAxes
        ColorHistTab                    matlab.ui.container.Tab
        UIAxesHist                      matlab.ui.control.UIAxes
        ColorNumberPanel                matlab.ui.container.Panel
        Slider                          matlab.ui.control.Slider
    end

    
    properties (Access = private)
        image % 图像
        colorNum % 颜色数量
        colorShowNum % 颜色显示数量
        clusterImage % 聚类后的图片
        clusterCenter % 聚类中心
        clusterHist %　聚类hist
    end
    
    methods (Access = private)
        
        function loadImage(app,path)
            image=imread(path);
            if size(image,1)>1000 || size(image,2)>1000
                if size(image,1)>1000
                    ns=[1000,1000*size(image,2)/size(image,1)];
                else
                    ns=[1000*size(image,1)/size(image,2),1000];
                end
                app.image=imresize(image,ns);
            else
                app.image=image;
            end
        end
        
        function colorCluster(app)
            %colorCluster Image color cluster function based on k-means.
            %   Author: Dorad, cug.xia@gmail.com
            % image: m*n*3, RGB mode.
            % colorNum: the total color number for cluster
            
            % reshape the image size to m*n*3
            data=reshape(app.image,[size(app.image,1)*size(app.image,2),3]);
            data=double(data)/255;
            %% cluster 
            % k-means cluster
            [idx,center]=kmeans(data,app.colorNum);
            
            %% calculate the hist and imageCluster
            center=uint8(center*255);
            % imageCluster
            imageCluster=zeros(size(app.image));
            idxn=reshape(idx,[size(app.image,1),size(app.image,2)]);
            for r=1:size(app.image,1)
                for c=1:size(app.image,2)
                    imageCluster(r,c,:)=center(idxn(r,c),:);
                end
            end
            app.clusterImage=uint8(imageCluster);
            % hist
            hist=zeros(size(center,1),1);
            for i=1:size(center,1)
                hist(i)=sum(idx==i);
            end
            [hist,hidx]=sort(hist,'descend');
            app.clusterCenter=center(hidx,:);
            app.clusterHist=hist;
        end
        
        function refreshAxes(app)
            if ~isempty(app.image)
                imshow(app.image,'Parent',app.UIAxesOirigin);
            end
            if ~isempty(app.clusterImage)
                imshow(app.clusterImage,'Parent',app.UIAxesCluster);
            end
            if ~isempty(app.clusterHist)
                app.Slider.Limits=[1,length(app.clusterHist)];
                
                p=app.clusterHist/sum(app.clusterHist)*100;
                app.Slider.Value=app.colorShowNum;
                f=bar(app.UIAxesHist,1:app.colorShowNum,p(1:app.colorShowNum),'FaceColor','flat');
                f.CData=app.clusterCenter(1:app.colorShowNum,:);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ss=get(0,'ScreenSize');
            fs=app.ImageColorAnalysisToolUIFigure.Position;
            app.ImageColorAnalysisToolUIFigure.Position=[ss(3)/2-fs(3)/2,ss(4)/2-fs(4)/2,fs(3),fs(4)];
            app.image=[];
            app.colorNum=15;
            app.colorShowNum=15;
            app.clusterImage=[];
            app.clusterCenter=[];
            app.clusterHist=[];
            
            app.SaveMenu.Enable=false;
            app.RunMenu.Enable=false;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,path] = uigetfile({'*.jpg';'*.png';'*.jpeg'},'Image File Select');
            if ~file
               return 
            end
            app.loadImage([path,file]);
            app.clusterCenter=[];
            app.clusterHist=[];
            app.clusterImage=[];
            
            app.SaveMenu.Enable=false;
            app.RunMenu.Enable=false;
            app.refreshAxes();
        end

        % Menu selected function: SetClusterNumberMenu
        function SetClusterNumberMenuSelected(app, event)
            answer=inputdlg('Color Number:','Set Color Number',[1,50],{num2str(app.colorNum)});
            if ~isempty(answer)
                app.colorNum=str2num(answer{1});
                app.colorShowNum=app.colorNum;
                app.RunMenu.Enable=true;
            end
        end

        % Menu selected function: RunMenu
        function RunMenuSelected(app, event)
            app.colorCluster();
            app.SaveMenu.Enable=true;
            app.refreshAxes();
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value=round(app.Slider.Value);
            app.colorShowNum=app.Slider.Value;
            app.refreshAxes();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            helpdlg({'This software is designed to analysis image color by k-means cluster method.','Blog: https://blog.cuger.cn','Dorad,cug.xia@cug.edu.cn'},'About');
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.image=[];
            app.RunMenu.Enable=false;
            app.clusterCenter=[];
            app.clusterHist=[];
            app.clusterImage=[];
            
            app.SaveMenu.Enable=false;
            app.RunMenu.Enable=false;
            app.refreshAxes();
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app.ImageColorAnalysisToolUIFigure);
        end

        % Menu selected function: ClusterImageMenu
        function ClusterImageMenuSelected(app, event)
            [file,path,~] =uiputfile(['Cluster-image-',datestr(datetime,'yyyymmddHHMMSS'),'.jpg']);
            if ~file
               return 
            end
            imwrite(app.clusterImage,[path,file]);
        end

        % Menu selected function: ColorHistImageMenu
        function ColorHistImageMenuSelected(app, event)
            [file,path,~] =uiputfile(['Color-hist-image-',datestr(datetime,'yyyymmddHHMMSS'),'.jpg']);
            if ~file
                return
            end
            f=figure('visible','off');
            p=app.clusterHist/sum(app.clusterHist)*100;
            ax=bar(1:app.colorShowNum,p(1:app.colorShowNum),'FaceColor','flat');
            ax.CData=app.clusterCenter(1:app.colorShowNum,:);
            print(f,'-djpeg',[path,file]);
            delete(f);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageColorAnalysisToolUIFigure = uifigure;
            app.ImageColorAnalysisToolUIFigure.Name = 'ImageColorAnalysisTool';
            app.FileMenu = uimenu(app.ImageColorAnalysisToolUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.ClusterImageMenu = uimenu(app.SaveMenu);
            app.ClusterImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ClusterImageMenuSelected, true);
            app.ClusterImageMenu.Text = 'Cluster Image';
            app.ColorHistImageMenu = uimenu(app.SaveMenu);
            app.ColorHistImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorHistImageMenuSelected, true);
            app.ColorHistImageMenu.Text = 'Color Hist Image';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.AnalysisMenu = uimenu(app.ImageColorAnalysisToolUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.SetClusterNumberMenu = uimenu(app.AnalysisMenu);
            app.SetClusterNumberMenu.MenuSelectedFcn = createCallbackFcn(app, @SetClusterNumberMenuSelected, true);
            app.SetClusterNumberMenu.Text = 'Set Cluster Number';
            app.RunMenu = uimenu(app.AnalysisMenu);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMenuSelected, true);
            app.RunMenu.Text = 'Run';
            app.AboutMenu = uimenu(app.ImageColorAnalysisToolUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.ImageColorAnalysisToolUIFigure);
            app.OriginTab = uitab(app.TabGroup);
            app.OriginTab.Title = 'Origin';
            app.UIAxesOirigin = uiaxes(app.OriginTab);
            title(app.UIAxesOirigin, 'Oirgin Image')
            app.UIAxesOirigin.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesOirigin.XTickLabel = '';
            app.UIAxesOirigin.YTickLabel = '';
            app.ClusterTab = uitab(app.TabGroup);
            app.ClusterTab.Title = 'Cluster';
            app.UIAxesCluster = uiaxes(app.ClusterTab);
            title(app.UIAxesCluster, 'Cluster Image')
            app.UIAxesCluster.XTickLabel = '';
            app.UIAxesCluster.YTickLabel = '';
            app.ColorHistTab = uitab(app.TabGroup);
            app.ColorHistTab.Title = 'Color Hist';
            app.UIAxesHist = uiaxes(app.ColorHistTab);
            title(app.UIAxesHist, 'Color Histogram')
            xlabel(app.UIAxesHist, 'Color')
            ylabel(app.UIAxesHist, 'Percentage/%')
            app.UIAxesHist.XTick = [];
            app.UIAxesHist.XTickLabel = '';
            app.UIAxesHist.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ColorNumberPanel = uipanel(app.ColorHistTab);
            app.ColorNumberPanel.Title = 'Color Number';
            app.Slider = uislider(app.ColorNumberPanel);
            app.Slider.Limits = [0 50];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = mImageColorAnalysisTool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageColorAnalysisToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageColorAnalysisToolUIFigure)
        end
    end
end